classdef RobotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DisengageESTOPButton       matlab.ui.control.Button
        OrderFoodButton            matlab.ui.control.Button
        HOMEButton                 matlab.ui.control.Button
        ESTOPStatusLamp            matlab.ui.control.Lamp
        ESTOPStatusLampLabel       matlab.ui.control.Label
        CurrentZPosEditField       matlab.ui.control.NumericEditField
        CurrentZPosEditFieldLabel  matlab.ui.control.Label
        CurrentYPosEditField       matlab.ui.control.NumericEditField
        CurrentYPosEditFieldLabel  matlab.ui.control.Label
        CurrentXPosEditField       matlab.ui.control.NumericEditField
        CurrentXPosEditFieldLabel  matlab.ui.control.Label
        MovetoXYZPositionLabel     matlab.ui.control.Label
        InputJointAngleLabel       matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        MOVEButton                 matlab.ui.control.StateButton
        ESTOPButton                matlab.ui.control.StateButton
        q6Spinner                  matlab.ui.control.Spinner
        q6SpinnerLabel             matlab.ui.control.Label
        q5Spinner                  matlab.ui.control.Spinner
        q5SpinnerLabel             matlab.ui.control.Label
        q4Spinner                  matlab.ui.control.Spinner
        q4SpinnerLabel             matlab.ui.control.Label
        q3Spinner                  matlab.ui.control.Spinner
        q3SpinnerLabel             matlab.ui.control.Label
        q2Spinner                  matlab.ui.control.Spinner
        q2SpinnerLabel             matlab.ui.control.Label
        q1Spinner                  matlab.ui.control.Spinner
        q1SpinnerLabel             matlab.ui.control.Label
        YumchaBotGUILabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        robot
        q
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis equal
           
            app.robot = UR3e; % Assuming UR3e() initializes the robot model
            app.q = zeros(1, 6); % Initial joint angles
            app.robot.model.animate(app.q); % Animate the robot at home position
        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [deg2rad(app.q1Spinner.Value), qcurrentPos(1,2),qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), deg2rad(app.q2Spinner.Value), qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), deg2rad(app.q3Spinner.Value), qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), deg2rad(app.q4Spinner.Value), qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), deg2rad(app.q5Spinner.Value), qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), qcurrentPos(1,5), deg2rad(app.q6Spinner.Value)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
           app.ESTOPStatusLamp.Color = 'r'; 
               assignin("base", "ESTOP", app.DisengageESTOPButton.Value)
            
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            if app.ESTOPButton.Value == 0
                qcurrentPos = app.robot.model.getpos();
                qHome = zeros(1,6); 
                qtrajHome = jtraj(qcurrentPos, qHome, 50);

                for i = 1:50
                    app.robot.model.animate(qtrajHome(i,:));
                    pause(0.01);
                end
                
            end    
        end

        % Button pushed function: DisengageESTOPButton
        function DisengageESTOPButtonPushed(app, event)
            app.ESTOPStatusLamp.Color = 'g';
            app.DisengageESTOPButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YumchaBotGUILabel = uilabel(app.UIFigure);
            app.YumchaBotGUILabel.Text = 'YumchaBot GUI';
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.Limits = [-90 90];
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-90 90];
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-90 90];
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-90 90];
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-90 90];
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-90 90];
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.MOVEButton = uibutton(app.UIFigure, 'state');
            app.MOVEButton.Text = 'MOVE';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputJointAngleLabel = uilabel(app.UIFigure);
            app.InputJointAngleLabel.Text = 'Input Joint Angle';
            app.MovetoXYZPositionLabel = uilabel(app.UIFigure);
            app.MovetoXYZPositionLabel.Text = 'Move to X, Y, Z Position';
            app.CurrentXPosEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXPosEditFieldLabel.Text = 'Current X Pos';
            app.CurrentXPosEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYPosEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYPosEditFieldLabel.Text = 'Current Y Pos';
            app.CurrentYPosEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentZPosEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentZPosEditFieldLabel.Text = 'Current Z Pos';
            app.CurrentZPosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ESTOPStatusLampLabel = uilabel(app.UIFigure);
            app.ESTOPStatusLampLabel.Text = 'ESTOP Status: ';
            app.ESTOPStatusLamp = uilamp(app.UIFigure);
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.OrderFoodButton = uibutton(app.UIFigure, 'push');
            app.OrderFoodButton.Text = 'Order Food';
            app.DisengageESTOPButton = uibutton(app.UIFigure, 'push');
            app.DisengageESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DisengageESTOPButtonPushed, true);
            app.DisengageESTOPButton.Text = 'Disengage ESTOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end